classdef ProjektKS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Ukad1Label                matlab.ui.control.Label
        ButtonUklad1              matlab.ui.control.Button
        ButtonUklad2              matlab.ui.control.Button
        Ukad2Label                matlab.ui.control.Label
        ButtonUklad3              matlab.ui.control.Button
        Ukad3Label                matlab.ui.control.Label
        ButtonUklad4              matlab.ui.control.Button
        Ukad4Label                matlab.ui.control.Label
        ElementGroup              matlab.ui.container.ButtonGroup
        RezystorButton            matlab.ui.control.RadioButton
        CewkaButton               matlab.ui.control.RadioButton
        KondensatorButton         matlab.ui.control.RadioButton
        Reset                     matlab.ui.control.Button
        Transmitancje             matlab.ui.control.UIAxes
        TransmitancjaLabel        matlab.ui.control.Label
        CzestotliwoscSlider       matlab.ui.control.Slider
        CzestotliwoscLabel        matlab.ui.control.Label
        JednostkaCzesto           matlab.ui.control.DropDown
        RezystorLabel             matlab.ui.control.Label
        RezystorWart              matlab.ui.control.NumericEditField
        RezystorJedn              matlab.ui.control.DropDown
        CewkaLabel                matlab.ui.control.Label
        CewkaWart                 matlab.ui.control.NumericEditField
        KondensatorLabel          matlab.ui.control.Label
        KondensatorWart           matlab.ui.control.NumericEditField
        CewkaJedn                 matlab.ui.control.DropDown
        KondensatorJedn           matlab.ui.control.DropDown
        ObliczButton              matlab.ui.control.Button
        BodeShowAmpl              matlab.ui.control.UIAxes
        BodeShowPhas              matlab.ui.control.UIAxes
        Switch                    matlab.ui.control.Switch
        wejsciowe                 matlab.ui.container.ButtonGroup
        EButton                   matlab.ui.control.RadioButton
        Esin2fButton              matlab.ui.control.RadioButton
        NapiecieSlider            matlab.ui.control.Slider
        NapiecieLabel             matlab.ui.control.Label
        JednostkaNapie            matlab.ui.control.DropDown
        CzestReczne               matlab.ui.control.NumericEditField
        AmplReczne                matlab.ui.control.NumericEditField
        Spokoprogramver50liczywszystkoanawetwicejLabel  matlab.ui.control.Label
        PrezentacjaDropDownLabel  matlab.ui.control.Label
        PrezentacjaDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        rezystor=1;
        cewka=1;
        kondensator=1;
        freq=1000;
        napi=0;
        funkcja='Bode';
        wybrany_uklad=1;
        HS=tf([1],[1]);
        ButtonH; % Uchwyt do przycisku okienka oczekiwania
        Tekst_Obliczanie; % Uchwyt do tekstu okienka oczekiwania
        wybrane_zrodlo=0; % Zapamietuje wybrane zrodlo; 0-const , 1-sin
        
    end
    
    methods (Access = private)
        
        function results = show_elements(app)    
            app.CzestotliwoscSlider.Visible='on';

            app.NapiecieSlider.Visible='on';
            app.ElementGroup.Visible='on';
            app.wejsciowe.Visible='on';
            app.CzestotliwoscLabel.Visible='on';
            app.NapiecieLabel.Visible='on';
            app.JednostkaCzesto.Visible='on';
            app.JednostkaNapie.Visible='on';
            app.ObliczButton.Visible='on';
            app.ObliczButton.Enable='off';
            %Wlacza jednostki i wartosci
            app.RezystorJedn.Visible='on';
            app.RezystorWart.Visible='on';
            app.CewkaWart.Visible='on';
            app.CewkaJedn.Visible='on';
            app.KondensatorJedn.Visible='on';
            app.KondensatorWart.Visible='on';
            app.KondensatorLabel.Visible='on';
            app.CewkaLabel.Visible='on';
            app.RezystorLabel.Visible='on';
            app.Switch.Visible='on';
            app.CzestReczne.Visible='on';
            app.AmplReczne.Visible='on'; 
            app.RezystorButton.Visible='on';
            app.CewkaButton.Visible='on';
            app.KondensatorButton.Visible='on';
            app.Esin2fButton.Visible='on';
            app.EButton.Visible='on';
            app.WyborFunkcji;
        end
        
    %%%%%%%%%%%%%% Funkcja resetujaca %%%%%%%%%%%%%%%%%%%%%5
        function results = ResetAll(app)
            app.ButtonUklad1.Enable='on';
            app.ButtonUklad2.Enable='on';
            app.ButtonUklad3.Enable='on';
            app.ButtonUklad4.Enable='on';
            app.ElementGroup.Visible='off';
            app.wejsciowe.Visible='off';
            app.ObliczButton.Visible='off';
            %Wylacza czestotliwosc i napiecie
            app.CzestotliwoscSlider.Visible='off';
            app.CzestotliwoscLabel.Visible='off';
            app.JednostkaCzesto.Visible='off';
            app.NapiecieSlider.Visible='off';
            app.NapiecieLabel.Visible='off';
            app.JednostkaNapie.Visible='off';
            %Wylacza jednostki i wartosci
            app.RezystorJedn.Visible='off';
            app.RezystorWart.Visible='off';
            app.CewkaWart.Visible='off';
            app.CewkaJedn.Visible='off';
            app.KondensatorJedn.Visible='off';
            app.KondensatorWart.Visible='off';
            app.KondensatorLabel.Visible='off';
            app.CewkaLabel.Visible='off';
            app.RezystorLabel.Visible='off';
            %Resetuje elementy
            app.RezystorJedn.Value='Ω';
            app.RezystorWart.Value=1;
            app.CewkaJedn.Value='nH';
            app.CewkaWart.Value=1;
            app.KondensatorWart.Value=1;
            app.KondensatorJedn.Value='nF';
            app.RezystorValue;
            app.CewkaValue;
            app.KondensatorValue;
            %Wylacza wykresy
            app.Switch.Visible='off';
            app.CzestReczne.Visible='off';
            app.AmplReczne.Visible='off';
            ResetPrzelacznika(app);


        end
        function results=oblicz_transmitancje(app,rezystor,cewka,kondensator,wybrano)
            waitfor 1;
             
            
            switch wybrano
                
                case 1
                    if app.RezystorButton.Value
                        app.HS=tf([rezystor*cewka*kondensator 0 rezystor],[rezystor*cewka*kondensator cewka rezystor]);%potwierdzam
                    else
                        app.HS=tf([cewka 0],[rezystor*cewka*kondensator cewka rezystor]); %potwierdzam
                    end
                case 2
                    if app.CewkaButton.Value
                        app.HS=tf([rezystor*cewka*kondensator cewka 0],[rezystor*cewka*kondensator cewka rezystor]); %potwierdzam
                    else
                        app.HS=tf([rezystor],[rezystor*cewka*kondensator cewka rezystor]); %potwierdzam
                    end
                case 3
                    if app.KondensatorButton.Value
                        app.HS=tf([cewka rezystor],[rezystor*cewka*kondensator cewka rezystor]); %potwierdzam
                    else
                        app.HS=tf([rezystor*cewka*kondensator 0 0],[rezystor*cewka*kondensator cewka rezystor]); %Sprawdź to, bo całe zmieniłem...
                    end
                case 4
                    if app.RezystorButton.Value
                        app.HS=tf([rezystor*kondensator 0], [cewka*kondensator rezystor*kondensator 1]); %potwierdzam
                    elseif app.KondensatorButton.Value
                        app.HS=tf([1],[cewka*kondensator rezystor*kondensator 1]); %potwierdzam
                    elseif app.CewkaButton.Value
                        app.HS=tf([cewka*kondensator 0 0],[cewka*kondensator rezystor*kondensator 1]); %potwierdzam
                    end           
            end
        end
        
        
        function results = oblicz_bode(app,rezystor,cewka,kondensator,wybrano)
            oblicz_transmitancje(app,rezystor,cewka,kondensator,wybrano);
            [mag,ph,wout]=bode(app.HS);
            semilogx(app.BodeShowAmpl,wout/(2*pi),20*log10(squeeze(mag)));
                app.BodeShowAmpl.XScale='log';
                app.BodeShowAmpl.YScale='linear';
                app.BodeShowAmpl.XLabel.String='Czestotliwosc[Hz]';
                app.BodeShowAmpl.YLabel.String='Wzmocnienie[dB]';
                app.BodeShowAmpl.Title.String='Amplituda';
                app.BodeShowAmpl.XGrid='on';
            semilogx(app.BodeShowPhas,wout/(2*pi),squeeze(ph));
                app.BodeShowPhas.XScale='log';
                app.BodeShowPhas.YScale='linear';
                app.BodeShowPhas.XLabel.String='Czestotliwosc[Hz]';
                app.BodeShowPhas.YLabel.String='Stopnie';
                app.BodeShowPhas.Title.String='Faza';
                app.BodeShowPhas.XGrid='on';


        end 
        
        function results = oblicz_nieustalone(app,rezystor,cewka,kondensator,freq,napi,wybrano)
             
            oblicz_transmitancje(app,rezystor,cewka,kondensator,wybrano);
            
                if app.EButton.Value
                    zasila=tf([napi],[1 0]);
                    freq=100000000;

                else
                    zasila=tf([napi*2*pi*freq],[1 0 4*pi^2*freq^2]);

                    
                end
                
                wyjscioweHS=zasila*app.HS;               %wyjsciowe w postaci tf
                wyjsciowesym=tosyms(app,wyjscioweHS);     %wyjsciowe w sym
                syms t;
                wyjsciowe(t)=ilaplace(wyjsciowesym);        %zamiana na dziedzine czasu
                dt1=linspace(0,5/freq,100);
                dt2=linspace(0,70/freq,1000);
                
                app.BodeShowAmpl.XScale='linear';
                app.BodeShowAmpl.YScale='linear';
                app.BodeShowAmpl.XLabel.String='Czas [s]';
                app.BodeShowAmpl.YLabel.String='Napiecie [V]';
                app.BodeShowAmpl.Title.String='Napięcie w pierwszych okresach po włączeniu';
                app.BodeShowAmpl.XGrid='on';
                plot(app.BodeShowAmpl,dt1,wyjsciowe(dt1));
                
                app.BodeShowPhas.XScale='linear';
                app.BodeShowPhas.YScale='linear';
                app.BodeShowPhas.XLabel.String='Czas [s]';
                app.BodeShowPhas.YLabel.String='Napiecie [V]';
                app.BodeShowPhas.Title.String='Napięcie po wielu okresach';
                app.BodeShowPhas.XGrid='on';
                plot(app.BodeShowPhas,dt2,wyjsciowe(dt2));
                %plot(dt,wyjsciowe(dt));
                %fplot(wyjsciowe)

                
        end 
        
        
        function results = ktory_uklad(app)
            if strcmp(app.ButtonUklad1.Enable,'off')
                app.wybrany_uklad = 1;
            elseif strcmp(app.ButtonUklad2.Enable,'off')
                app.wybrany_uklad= 2;
            elseif strcmp(app.ButtonUklad3.Enable,'off')
                app.wybrany_uklad = 3;
            elseif strcmp(app.ButtonUklad4.Enable,'off')
                app.wybrany_uklad = 4;
            end
            results=app.wybrany_uklad;
        end
        
        
        function [sym] = tosyms(app,YS)   % zamienia tf na symbol
            [licznik, mianownik]=tfdata(YS);
            syms s;
            sym=poly2sym(licznik,s)/poly2sym(mianownik,s);
        end
        
        
        function results = ResetPrzelacznika(app)   %Resetuje wykresy
                app.CzestotliwoscLabel.Enable='off';
                app.CzestotliwoscSlider.Enable='off';
                app.NapiecieLabel.Enable='off';
                app.NapiecieSlider.Enable='off';
                app.JednostkaCzesto.Enable='off';
                app.JednostkaNapie.Enable='off';
                app.CzestReczne.Enable='off';
                app.AmplReczne.Enable='off';
                app.BodeShowAmpl.Visible='off';
                app.BodeShowPhas.Visible='off';
                semilogx(app.BodeShowAmpl,0,0);
                semilogx(app.BodeShowPhas,0,0);
                plot(app.Transmitancje,0,0);
                app.Transmitancje.Visible='off';
                app.TransmitancjaLabel.Visible='off';       
                close all;
                
        end
        
        
        function results = Okienko(app,etap)
            switch etap
                case 0
                Liczenie=uifigure('Name','Zajęty','Position',[-1400 -1760 200 100],'CloseRequestFcn',' ','Resize','off','WindowState','normal');
                movegui(Liczenie,'center');
                app.ButtonH=uibutton(Liczenie,'Text','STOP','Visible','on','Position',[60 20 80 30],'ButtonPushedFcn','closereq','Enable','off');
                app.Tekst_Obliczanie=uilabel(Liczenie,'Text','Rozpoczynanie','Position',[60 60 110 30],'FontSize',12);
                uifigureOnTop(app,Liczenie,true);
                set(app.UIFigure,'CloseRequestFcn', ' ');
                case 1
                    set(app.Tekst_Obliczanie,'Text','Trwa obliczanie, czekaj...');
                    set(app.Tekst_Obliczanie,'Position',[35 60 178 30]);
                    set(app.ButtonH,'Text','Czekaj');
                    set(app.ButtonH, 'enable', 'off');
                    app.ButtonUklad1.Enable = 'off';
                    app.ButtonUklad2.Enable = 'off';
                    app.ButtonUklad3.Enable = 'off';
                    app.ButtonUklad4.Enable = 'off';
                    app.RezystorButton.Enable = 'off';
                    app.CewkaButton.Enable = 'off';
                    app.CewkaJedn.Enable = 'off';
                    app.CewkaWart.Enable = 'off';
                    app.Switch.Enable = 'off';
                    app.Reset.Enable = 'off';
                    app.RezystorJedn.Enable = 'off';
                    app.RezystorWart.Enable = 'off';
                    app.KondensatorButton.Enable = 'off';
                    app.KondensatorJedn.Enable = 'off';
                    app.KondensatorWart.Enable = 'off';
                    app.BodeShowAmpl.Visible='off';
                    app.BodeShowPhas.Visible='off';
                    semilogx(app.BodeShowAmpl,0,0);
                    semilogx(app.BodeShowPhas,0,0);
                    app.ObliczButton.Enable = 'off';
                    app.EButton.Enable = 'off';
                    app.Esin2fButton.Enable = 'off';
                    app.NapiecieSlider.Enable = 'off';
                    app.CzestReczne.Enable = 'off';
                    app.CzestotliwoscSlider.Enable = 'off';
                    app.AmplReczne.Enable = 'off';
                    app.JednostkaCzesto.Enable = 'off';
                    app.JednostkaNapie.Enable = 'off';
                    
                
                case 2
                    set(app.Tekst_Obliczanie,'Text','Obliczono!');
                    set(app.Tekst_Obliczanie,'Position', [70 60 80 30]);
                    set(app.ButtonH, 'enable', 'on');
                    set(app.ButtonH,'Text','OK');
                    app.ButtonUklad1.Enable = 'on';
                    app.ButtonUklad2.Enable = 'on';
                    app.ButtonUklad3.Enable = 'on';
                    app.ButtonUklad4.Enable = 'on';
                    if(app.wybrany_uklad == 1)  
                        app.ButtonUklad1.Enable = 'off';end
                    if(app.wybrany_uklad == 2)  
                        app.ButtonUklad2.Enable = 'off';end
                    if(app.wybrany_uklad == 3)  
                        app.ButtonUklad3.Enable = 'off';end
                    if(app.wybrany_uklad == 4)  
                        app.ButtonUklad4.Enable = 'off';end
                    app.RezystorButton.Enable = 'on';
                    app.CewkaButton.Enable = 'on';
                    app.CewkaJedn.Enable = 'on';
                    app.CewkaWart.Enable = 'on';
                    app.Switch.Enable = 'on';
                    app.Reset.Enable = 'on';
                    app.RezystorJedn.Enable = 'on';
                    app.RezystorWart.Enable = 'on';
                    app.KondensatorButton.Enable = 'on';
                    app.KondensatorJedn.Enable = 'on';
                    app.KondensatorWart.Enable = 'on';
                    app.ObliczButton.Enable = 'on';
                    set(app.UIFigure,'CloseRequestFcn', 'closereq');
                case 3
                    set(app.Tekst_Obliczanie,'Text','Obliczono!');
                    set(app.Tekst_Obliczanie,'Position', [70 60 80 30]);
                    set(app.ButtonH, 'enable', 'on');
                    set(app.ButtonH,'Text','OK');
                    
                    app.ButtonUklad1.Enable = 'on';
                    app.ButtonUklad2.Enable = 'on';
                    app.ButtonUklad3.Enable = 'on';
                    app.ButtonUklad4.Enable = 'on';
                    if(app.wybrany_uklad == 1)  
                        app.ButtonUklad1.Enable = 'off';end
                    if(app.wybrany_uklad == 2)  
                        app.ButtonUklad2.Enable = 'off';end
                    if(app.wybrany_uklad == 3)  
                        app.ButtonUklad3.Enable = 'off';end
                    if(app.wybrany_uklad == 4)  
                        app.ButtonUklad4.Enable = 'off';end
                    app.RezystorButton.Enable = 'on';
                    app.AmplReczne.Enable = 'on';
                    app.CewkaButton.Enable = 'on';
                    app.CewkaJedn.Enable = 'on';
                    app.CewkaWart.Enable = 'on';
                    app.Switch.Enable = 'on';
                    app.EButton.Enable = 'on';
                    app.Esin2fButton.Enable = 'on';
                    app.NapiecieSlider.Enable = 'on';
                    app.Reset.Enable = 'on';
                    app.JednostkaNapie.Enable = 'on';
                    app.RezystorJedn.Enable = 'on';
                    app.RezystorWart.Enable = 'on';
                    app.KondensatorButton.Enable = 'on';
                    app.KondensatorJedn.Enable = 'on';
                    app.KondensatorWart.Enable = 'on';
                    app.ObliczButton.Enable = 'on';
                    set(app.UIFigure,'CloseRequestFcn', 'closereq');
                case 4
                    set(app.Tekst_Obliczanie,'Text','Obliczono!');
                    set(app.Tekst_Obliczanie,'Position', [70 60 80 30]);
                    set(app.ButtonH, 'enable', 'on');
                    set(app.ButtonH,'Text','OK');
                    
                    app.ButtonUklad1.Enable = 'on';
                    app.ButtonUklad2.Enable = 'on';
                    app.ButtonUklad3.Enable = 'on';
                    app.ButtonUklad4.Enable = 'on';
                    if(app.wybrany_uklad == 1)  
                        app.ButtonUklad1.Enable = 'off';end
                    if(app.wybrany_uklad == 2)  
                        app.ButtonUklad2.Enable = 'off';end
                    if(app.wybrany_uklad == 3)  
                        app.ButtonUklad3.Enable = 'off';end
                    if(app.wybrany_uklad == 4)  
                        app.ButtonUklad4.Enable = 'off';end
                    app.RezystorButton.Enable = 'on';
                    app.AmplReczne.Enable = 'on';
                    app.CewkaButton.Enable = 'on';
                    app.CewkaJedn.Enable = 'on';
                    app.CewkaWart.Enable = 'on';
                    app.CzestotliwoscSlider.Enable = 'on';
                    app.Switch.Enable = 'on';
                    app.EButton.Enable = 'on';
                    app.Esin2fButton.Enable = 'on';
                    app.NapiecieSlider.Enable = 'on';
                    app.CzestReczne.Enable = 'on';
                    app.Reset.Enable = 'on';
                    app.JednostkaCzesto.Enable = 'on';
                    app.JednostkaNapie.Enable = 'on';
                    app.RezystorJedn.Enable = 'on';
                    app.RezystorWart.Enable = 'on';
                    app.KondensatorButton.Enable = 'on';
                    app.KondensatorJedn.Enable = 'on';
                    app.KondensatorWart.Enable = 'on';
                    app.ObliczButton.Enable = 'on';
                    set(app.UIFigure,'CloseRequestFcn', 'closereq');
            end
                    
            
        end
        
        function results = uifigureOnTop(app,uifig, isOnTop) % Funkcja z neta
            % https://ch.mathworks.com/matlabcentral/fileexchange/73134-uifigureontop
         
assert(...
          isscalar(  uifig ) &&...
          isa( uifig,'matlab.ui.Figure'),...
          ...
          'uifigureOnTop:Bad_uifig_input',...
          '%s','Provided uifig input is not an uifigure'...
       );
assert(...
            strcmp('on',get(uifig,'Visible')),...
            'uifigureOnTop:FigInisible',...
            '%s','Figure Must be Visible'...
       );
assert(...
            strcmp('normal',get(uifig,'WindowStyle')),...
            'uifigureOnTop:FigWrongWindowStyle',...
            '%s','WindowStyle Must be Normal'...
       );
   
if ~exist('isOnTop','var'); isOnTop=true; end
assert(...
          islogical( isOnTop ) && ...
          isscalar(  isOnTop ) || ...
          isempty(   isOnTop ),  ...
          ...
          'uifigureOnTop:Bad_isOnTop_input',...
          '%s','Provided isOnTop input is neither boolean, nor empty'...
      );
drawnow expose

warn1_id = 'MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame';
prev_warn1 = warning('query',warn1_id);
clnup1=onCleanup(@() warning(prev_warn1.state,warn1_id));
warning('off',warn1_id);

warn2_id = 'MATLAB:structOnObject';
prev_warn2 = warning('query',warn2_id);
clnup2=onCleanup(@() warning(prev_warn2.state,warn2_id));
warning('off',warn2_id);
figProps = struct(uifig); 
delete(clnup1);    
controller = figProps.Controller; 
controllerProps = struct(controller);
if isfield(controllerProps,'Container')
    container = controllerProps.Container;
else
    container = struct(controllerProps.PlatformHost);
end
delete(clnup2);

win = container.CEF;
wasOnTop = win.isAlwaysOnTop;
if isempty(wasOnTop)
    wasOnTop = false;
end
if ~isempty(isOnTop)
    win.setAlwaysOnTop(isOnTop);
end
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function results=jestrezonans(app)
            app.CewkaJedn.Items=["nH" "μH" "mH" "H" "kH" "Dostosuj dla rezonansu"];
            app.KondensatorJedn.Items=["nF" "μF" "mF" "F" "kF" "Dostosuj dla rezonansu"];
            app.JednostkaCzesto.Items=["Hz" "kHz" "MHz" "Dostosuj dla rezonansu"];
        
        end

        function results=niemarezonansu(app)
            app.CewkaJedn.Items=["nH" "μH" "mH" "H" "kH"];
            app.KondensatorJedn.Items=["nF" "μF" "mF" "F" "kF"];
            app.JednostkaCzesto.Items=["Hz" "kHz" "MHz"];
        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'north');
            clear all;
            close all;
            app.rezystor=1;
            app.cewka=1;
            app.kondensator=1;
            app.freq=1;
            app.napi=1;
            
            
        end

        % Button pushed function: ButtonUklad1
        function Uklad1Pushed(app, event)
            app.ResetButtonPushed;
            app.ButtonUklad1.Enable='off';
            %Załacza elementy interaktywne
            app.jestrezonans;
            app.show_elements;
        end

        % Button pushed function: ButtonUklad2
        function Uklad2Pushed(app, event)
            app.ResetButtonPushed;
            app.ButtonUklad2.Enable='off';
            %Załacza elementy interaktywne
            app.niemarezonansu;
            app.show_elements;
        end

        % Button pushed function: ButtonUklad3
        function Uklad3Pushed(app, event)
            app.ResetButtonPushed;
            app.ButtonUklad3.Enable='off';
            %Załacza elementy interaktywne
            app.niemarezonansu;
            app.show_elements;
        end

        % Button pushed function: ButtonUklad4
        function Uklad4Pushed(app, event)
            app.ResetButtonPushed;
            app.ButtonUklad4.Enable='off';
            %Załacza elementy interaktywne
            app.jestrezonans;
            app.show_elements;
        end

        % Selection changed function: ElementGroup
        function WyborElementuChanged2(app, event)
        wybrano=ktory_uklad(app);
       
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        if app.RezystorButton.Value | app.CewkaButton.Value | app.KondensatorButton.Value
            app.ObliczButton.Enable='on';
        end
        if wybrano == 1     % Transmitancje dla układu 1      
            if app.RezystorButton.Value
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad1_R.png', 'parent',app.Transmitancje);
            elseif app.CewkaButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad1_L.png', 'parent',app.Transmitancje);
            elseif app.KondensatorButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad1_C.png', 'parent',app.Transmitancje);
            end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        elseif wybrano == 2          %Transmitancje układu 2
            if app.RezystorButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad2_R.png', 'parent',app.Transmitancje);
            elseif app.CewkaButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad2_L.png', 'parent',app.Transmitancje);
            elseif app.KondensatorButton.Value   
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad2_C.png', 'parent',app.Transmitancje);
            end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        elseif wybrano == 3       %Transmitancje układu 3
            if app.RezystorButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad3_R.png', 'parent',app.Transmitancje);
            elseif app.CewkaButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad3_L.png', 'parent',app.Transmitancje);
            elseif app.KondensatorButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad3_C.png', 'parent',app.Transmitancje);
            end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        elseif wybrano == 4     %Transmitancje układu 4
            if app.RezystorButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad4_R.png', 'parent',app.Transmitancje);
            elseif app.CewkaButton.Value  
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad4_L.png', 'parent',app.Transmitancje);
            elseif app.KondensatorButton.Value 
                app.Transmitancje.Visible='on';
                app.TransmitancjaLabel.Visible='on';
                imshow('Uklad4_C.png', 'parent',app.Transmitancje);
            end
        end
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.ResetAll;
            
        end

        % Value changed function: JednostkaCzesto
        function JednostkaCzestoValue(app, event)
            value = app.JednostkaCzesto.Value;
            
            switch value
                case 'Hz'
                    app.freq=app.CzestotliwoscSlider.Value * 1;
                case 'kHz'
                    app.freq=app.CzestotliwoscSlider.Value * 1000;
                case 'MHz'
                    app.freq=app.CzestotliwoscSlider.Value * 1000000;
                case 'Dostosuj dla rezonansu'                   %%podstawia f rezonansową
                    app.freq=(1/(app.cewka*app.kondensator))^0.5/(2*pi);
                    if app.freq<1000
                        app.CzestReczne.Value=app.freq;
                        app.JednostkaCzesto.Value='Hz';
                        
                    elseif app.freq>=1000 & app.freq<1000000
                        app.CzestReczne.Value=app.freq/1000;
                        app.JednostkaCzesto.Value='kHz';
                        
                    else
                        app.CzestReczne.Value=app.freq/1000000;
                        app.JednostkaCzesto.Value='MHz';
                       
                    end
                    app.CzestReczneValueChanged;


            end
        end

        % Value changed function: CzestotliwoscSlider
        function CzestotliwoscValue(app, event)
            app.CzestReczne.Value=app.CzestotliwoscSlider.Value;
            app.JednostkaCzestoValue;    
        end

        % Value changed function: RezystorWart
        function RezystorValue(app, event)
           jednostka=app.RezystorJedn.Value;
           switch jednostka
                case 'Ω'
                    app.rezystor=app.RezystorWart.Value * 1e0;
                case 'kΩ'
                    app.rezystor=app.RezystorWart.Value * 1e3;
                case 'MΩ'
                    app.rezystor=app.RezystorWart.Value * 1e6;
                case 'GΩ'
                    app.rezystor=app.RezystorWart.Value * 1e9;
           end 
           
        end

        % Value changed function: RezystorJedn
        function RezystorJednVal(app, event)
            app.RezystorValue; %Tylko po to, zeby reagowala na zmiane jednostki rezystancji
        end

        % Value changed function: CewkaWart
        function CewkaValue(app, event)
            value=app.CewkaWart.Value;
            jednostka = app.CewkaJedn.Value;
            switch jednostka
                case 'nH'
                    app.cewka=value*1e-9;
                case 'μH'
                    app.cewka=value*1e-6;
                case 'mH'
                    app.cewka=value*1e-3;
                case 'H'
                    app.cewka=value*1e-0;
                case 'kH'
                    app.cewka=value*1e3;
                case 'Dostosuj dla rezonansu'                   %%podstawia f rezonansową
                    app.cewka=(1/((2*pi*app.freq)^2*app.kondensator));
                    if app.cewka<1e-6
                        app.CewkaWart.Value=app.cewka*1e9;
                        app.CewkaJedn.Value='nH';
                    elseif app.cewka>=1e-6 & app.cewka<1e-3
                        app.CewkaWart.Value=app.cewka*1e6;
                        app.CewkaJedn.Value='μH';
                    elseif app.cewka>=1e-3 & app.cewka<1
                        app.CewkaWart.Value=app.cewka*1e3;
                        app.CewkaJedn.Value='mH';
                    elseif app.cewka>=1 & app.cewka<1e3
                        app.CewkaWart.Value=app.cewka*1;
                        app.CewkaJedn.Value='H';
                    else
                        app.CewkaWart.Value=app.cewka*1e-3;
                        app.CewkaJedn.Value='kH';
                    end
            end
        end

        % Value changed function: CewkaJedn
        function CewkaJednChanged(app, event)
            app.CewkaValue; %Funkcja tylko po to, żeby reagował na zmianę jednostki
            
        end

        % Value changed function: KondensatorWart
        function KondensatorValue(app, event)
            value = app.KondensatorWart.Value;
            jednostka = app.KondensatorJedn.Value;
            switch jednostka
                case 'nF'
                    app.kondensator=value*1e-9;
                case 'μF'
                    app.kondensator=value*1e-6;
                case 'mF'
                    app.kondensator=value*1e-3;
                case 'F'
                    app.kondensator=value*1e-0;
                case 'kF'
                    app.kondensator=value*1e3;
                case 'Dostosuj dla rezonansu'                   %%podstawia f rezonansową
                    app.kondensator=(1/((2*pi*app.freq)^2*app.cewka));
                    if app.kondensator<1e-6
                        app.KondensatorWart.Value=app.kondensator*1e9;
                        app.KondensatorJedn.Value='nF';
                    elseif app.kondensator>=1e-6 & app.kondensator<1e-3
                        app.KondensatorWart.Value=app.kondensator*1e6;
                        app.KondensatorJedn.Value='μF';
                    elseif app.kondensator>=1e-3 & app.kondensator<1
                        app.KondensatorWart.Value=app.kondensator*1e3;
                        app.KondensatorJedn.Value='mF';
                    elseif app.kondensator>=1 & app.kondensator<1e3
                        app.KondensatorWart.Value=app.kondensator*1;
                        app.KondensatorJedn.Value='F';
                    else
                        app.KondensatorWart.Value=app.kondensator*1e-3;
                        app.KondensatorJedn.Value='kF';
                    end
            end
        end

        % Value changed function: KondensatorJedn
        function KondensatorJednChanged(app, event)
            app.KondensatorValue;       %Reaguje na zmiane jednostki pojemnosci
        end

        % Button pushed function: ObliczButton
        function ObliczPushed(app, event)
            app.Okienko(0);
            pause(0.5);
            if strcmp(app.funkcja,'Bode')
                app.Okienko(1);
                pause(1);
                oblicz_bode(app,app.rezystor,app.cewka,app.kondensator,app.wybrany_uklad);
                app.BodeShowAmpl.Visible='on';
                app.BodeShowPhas.Visible='on';
                app.Okienko(2);
            else
                if app.EButton.Value
                    app.wybrane_zrodlo=0;
                else
                    app.wybrane_zrodlo=1;
                end
                app.Okienko(1);
                pause(1);
                oblicz_nieustalone(app,app.rezystor,app.cewka,app.kondensator,app.freq,app.napi,app.wybrany_uklad);
                app.BodeShowAmpl.Visible='on';
                app.BodeShowPhas.Visible='on';
                
                if app.wybrane_zrodlo == 0
                    app.Okienko(3);
                else
                    app.Okienko(4);
                end
                
            end
            
        end

        % Value changed function: Switch
        function WyborFunkcji(app, event)
            app.funkcja = app.Switch.Value;
            app.ObliczButton.Enable='off';
            if strcmp(app.funkcja,'Nieustalone')
                ResetPrzelacznika(app);
                app.EButton.Value=1;
                app.CzestotliwoscLabel.Enable='off';
                app.CzestotliwoscSlider.Enable='off';
                app.JednostkaCzesto.Enable='off';
                app.NapiecieLabel.Enable='on';
                app.NapiecieSlider.Enable='on';
                app.JednostkaNapie.Enable='on';
                app.AmplReczne.Enable='on';
                app.EButton.Enable='on';
                app.Esin2fButton.Enable='on';
                app.jestrezonans;
                
                
            else
                ResetPrzelacznika(app);
                app.EButton.Enable='off';
                app.Esin2fButton.Enable='off';
                app.niemarezonansu;
            end
        end

        % Value changed function: NapiecieSlider
        function NapiecieValue(app, event)
            app.AmplReczne.Value=app.NapiecieSlider.Value;
            app.JednostkaNapieValue;
            
        end

        % Value changed function: JednostkaNapie
        function JednostkaNapieValue(app, event)
            value = app.JednostkaNapie.Value; % Tutaj do zmiany, trzeba wsadzić gdzie będzie mnożył wartość
            
            switch value
                case 'mV'
                    app.napi=app.NapiecieSlider.Value * 0.001;
                case 'V'
                    app.napi=app.NapiecieSlider.Value * 1;
                    
            end
            
        end

        % Selection changed function: wejsciowe
        function wejscioweSelectionChanged(app, event)
            if app.EButton.Value
                app.JednostkaCzesto.Enable='off';
                app.CzestotliwoscSlider.Enable='off';
                app.CzestReczne.Enable='off';
                app.CzestotliwoscLabel.Enable='off';
            else 
                app.JednostkaCzesto.Enable='on';
                app.CzestotliwoscSlider.Enable='on';
                app.CzestReczne.Enable='on';
                app.CzestotliwoscLabel.Enable='on';
            end
        end

        % Value changed function: CzestReczne
        function CzestReczneValueChanged(app, event)
            app.CzestotliwoscSlider.Value=app.CzestReczne.Value;
            app.CzestotliwoscValue;
        end

        % Value changed function: AmplReczne
        function AmplReczneValueChanged(app, event)
            app.NapiecieSlider.Value=app.AmplReczne.Value;
            app.JednostkaNapieValue;
            
        end

        % Value changed function: PrezentacjaDropDown
        function PrezentacjaCallback(app, event)
            przyklad = app.PrezentacjaDropDown.Value;
            switch przyklad
                case 'Tryb Normalny'
                    app.ResetButtonPushed;
                case 'Rezonans prądowy [PRZYKŁAD]'
                    app.Uklad1Pushed;
                    % przelacza na stany nieustalone
                    app.Switch.Value='Nieustalone';
                    app.WyborFunkcji;
                    %zmienia wybrany element na rezystor
                    app.RezystorButton.Value=1;
                    app.WyborElementuChanged2;
                    %   Wywoluje zmiane rezystancji
                    app.RezystorWart.Value=10;
                    app.RezystorJedn.Value='Ω';
                    app.RezystorValue;
                    % Wywoluje zmiane cewki
                    app.CewkaWart.Value=1;
                    app.CewkaJedn.Value='H';
                    app.CewkaValue;
                    % Wywoluje zmiane kondensatora
                    app.KondensatorWart.Value=15;
                    app.KondensatorJedn.Value='μF';
                    app.KondensatorValue;
                    % Wywoluje zmiane napiecia
                    app.JednostkaNapie.Value='V';
                    app.NapiecieSlider.Value=5;
                    app.NapiecieValue;
                    % Wywoluje zmiane zrodla
                    app.Esin2fButton.Value=1;
                    app.wejscioweSelectionChanged;
                    % Wywoluje zmiane czestotliwosci
                    app.CzestotliwoscSlider.Value=41.0936296;
                    app.JednostkaCzesto.Value='Hz';
                    app.CzestotliwoscValue;
                    % wykonuje obliczenia
                    app.ObliczPushed;
                case 'Rezonans napięć [PRZYKŁAD]'
                    app.Uklad4Pushed; 
                    % przelacza na stany nieustalone
                    app.Switch.Value='Nieustalone';
                    app.WyborFunkcji;
                    %zmienia wybrany element na rezystor
                    app.RezystorButton.Value=1;
                    app.WyborElementuChanged2;
                    %   Wywoluje zmiane rezystancji
                    app.RezystorWart.Value=10;
                    app.RezystorJedn.Value='Ω';
                    app.RezystorValue;
                    % Wywoluje zmiane cewki
                    app.CewkaWart.Value=1;
                    app.CewkaJedn.Value='H';
                    app.CewkaValue;
                    % Wywoluje zmiane kondensatora
                    app.KondensatorWart.Value=15;
                    app.KondensatorJedn.Value='μF';
                    app.KondensatorValue;
                    % Wywoluje zmiane napiecia
                    app.JednostkaNapie.Value='V';
                    app.NapiecieSlider.Value=5;
                    app.NapiecieValue;
                    % Wywoluje zmiane zrodla
                    app.Esin2fButton.Value=1;
                    app.wejscioweSelectionChanged;
                    % Wywoluje zmiane czestotliwosci
                    app.CzestotliwoscSlider.Value=41.0936296;
                    app.JednostkaCzesto.Value='Hz';
                    app.CzestotliwoscValue;
                    % wykonuje obliczenia
                    app.ObliczPushed;
                case 'Rezonans napięć Bode [PRZYKŁAD]'
                    app.Uklad4Pushed; 
                    % przelacza na stany nieustalone
                    app.Switch.Value='Bode';
                    app.WyborFunkcji;
                    %zmienia wybrany element na rezystor
                    app.RezystorButton.Value=1;
                    app.WyborElementuChanged2;
                    %   Wywoluje zmiane rezystancji
                    app.RezystorWart.Value=10;
                    app.RezystorJedn.Value='Ω';
                    app.RezystorValue;
                    % Wywoluje zmiane cewki
                    app.CewkaWart.Value=1;
                    app.CewkaJedn.Value='H';
                    app.CewkaValue;
                    % Wywoluje zmiane kondensatora
                    app.KondensatorWart.Value=15;
                    app.KondensatorJedn.Value='μF';
                    app.KondensatorValue;
                    % wykonuje obliczenia
                    app.ObliczPushed;
                case 'Rezonans prądów Bode [PRZYKŁAD]'
                    app.Uklad1Pushed; 
                    % przelacza na stany nieustalone
                    app.Switch.Value='Bode';
                    app.WyborFunkcji;
                    %zmienia wybrany element na rezystor
                    app.RezystorButton.Value=1;
                    app.WyborElementuChanged2;
                    %   Wywoluje zmiane rezystancji
                    app.RezystorWart.Value=10;
                    app.RezystorJedn.Value='Ω';
                    app.RezystorValue;
                    % Wywoluje zmiane cewki
                    app.CewkaWart.Value=1;
                    app.CewkaJedn.Value='H';
                    app.CewkaValue;
                    % Wywoluje zmiane kondensatora
                    app.KondensatorWart.Value=15;
                    app.KondensatorJedn.Value='μF';
                    app.KondensatorValue;
                    % wykonuje obliczenia
                    app.ObliczPushed;
                   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Ukad1Label = uilabel(app.UIFigure);
            app.Ukad1Label.Text = 'Układ 1';
            app.ButtonUklad1 = uibutton(app.UIFigure, 'push');
            app.ButtonUklad1.ButtonPushedFcn = createCallbackFcn(app, @Uklad1Pushed, true);
            app.ButtonUklad1.Icon = 'LC_R.JPG';
            app.ButtonUklad1.Text = '';
            app.ButtonUklad2 = uibutton(app.UIFigure, 'push');
            app.ButtonUklad2.ButtonPushedFcn = createCallbackFcn(app, @Uklad2Pushed, true);
            app.ButtonUklad2.Icon = 'RC_L.JPG';
            app.ButtonUklad2.Text = '';
            app.Ukad2Label = uilabel(app.UIFigure);
            app.Ukad2Label.Text = 'Układ 2';
            app.ButtonUklad3 = uibutton(app.UIFigure, 'push');
            app.ButtonUklad3.ButtonPushedFcn = createCallbackFcn(app, @Uklad3Pushed, true);
            app.ButtonUklad3.Icon = 'RL_C.JPG';
            app.ButtonUklad3.Text = '';
            app.Ukad3Label = uilabel(app.UIFigure);
            app.Ukad3Label.Text = 'Układ 3';
            app.ButtonUklad4 = uibutton(app.UIFigure, 'push');
            app.ButtonUklad4.ButtonPushedFcn = createCallbackFcn(app, @Uklad4Pushed, true);
            app.ButtonUklad4.Icon = 'RLC.JPG';
            app.ButtonUklad4.Text = '';
            app.Ukad4Label = uilabel(app.UIFigure);
            app.Ukad4Label.Text = 'Układ 4';
            app.ElementGroup = uibuttongroup(app.UIFigure);
            app.ElementGroup.SelectionChangedFcn = createCallbackFcn(app, @WyborElementuChanged2, true);
            app.ElementGroup.Title = 'Wybor Elementu';
            app.ElementGroup.Visible = 'off';
            app.RezystorButton = uiradiobutton(app.ElementGroup);
            app.RezystorButton.Visible = 'off';
            app.RezystorButton.Text = 'Rezystor';
            app.CewkaButton = uiradiobutton(app.ElementGroup);
            app.CewkaButton.Visible = 'off';
            app.CewkaButton.Text = 'Cewka';
            app.CewkaButton.Value = true;
            app.KondensatorButton = uiradiobutton(app.ElementGroup);
            app.KondensatorButton.Visible = 'off';
            app.KondensatorButton.Text = 'Kondensator';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Icon = 'Linia.png';
            app.Reset.Tooltip = {'Reset'};
            app.Reset.Text = '';
            app.Transmitancje = uiaxes(app.UIFigure);
            xlabel(app.Transmitancje, '')
            ylabel(app.Transmitancje, '')
            app.Transmitancje.Visible = 'off';
            app.TransmitancjaLabel = uilabel(app.UIFigure);
            app.TransmitancjaLabel.Visible = 'off';
            app.TransmitancjaLabel.Text = 'Transmitancja';
            app.CzestotliwoscSlider = uislider(app.UIFigure);
            app.CzestotliwoscSlider.Limits = [0 1000];
            app.CzestotliwoscSlider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.CzestotliwoscSlider.ValueChangedFcn = createCallbackFcn(app, @CzestotliwoscValue, true);
            app.CzestotliwoscSlider.Visible = 'off';
            app.CzestotliwoscLabel = uilabel(app.UIFigure);
            app.CzestotliwoscLabel.Visible = 'off';
            app.CzestotliwoscLabel.Text = 'Częstotliwość';
            app.JednostkaCzesto = uidropdown(app.UIFigure);
            app.JednostkaCzesto.Items = {'Hz', 'kHz', 'MHz', 'Dostosuj dla rezonansu'};
            app.JednostkaCzesto.ValueChangedFcn = createCallbackFcn(app, @JednostkaCzestoValue, true);
            app.JednostkaCzesto.Visible = 'off';
            app.JednostkaCzesto.Value = 'Dostosuj dla rezonansu';
            app.RezystorLabel = uilabel(app.UIFigure);
            app.RezystorLabel.Visible = 'off';
            app.RezystorLabel.Text = 'Rezystor';
            app.RezystorWart = uieditfield(app.UIFigure, 'numeric');
            app.RezystorWart.Limits = [1e-12 Inf];
            app.RezystorWart.ValueChangedFcn = createCallbackFcn(app, @RezystorValue, true);
            app.RezystorWart.Visible = 'off';
            app.RezystorWart.Value = 1;
            app.RezystorJedn = uidropdown(app.UIFigure);
            app.RezystorJedn.Items = {'Ω', 'kΩ', 'MΩ', 'GΩ'};
            app.RezystorJedn.ValueChangedFcn = createCallbackFcn(app, @RezystorJednVal, true);
            app.RezystorJedn.Visible = 'off';
            app.RezystorJedn.Value = 'Ω';
            app.CewkaLabel = uilabel(app.UIFigure);
            app.CewkaLabel.Visible = 'off';
            app.CewkaLabel.Text = {'Cewka'; ''};
            app.CewkaWart = uieditfield(app.UIFigure, 'numeric');
            app.CewkaWart.Limits = [0 Inf];
            app.CewkaWart.ValueChangedFcn = createCallbackFcn(app, @CewkaValue, true);
            app.CewkaWart.Visible = 'off';
            app.CewkaWart.Value = 1;
            app.KondensatorLabel = uilabel(app.UIFigure);
            app.KondensatorLabel.Visible = 'off';
            app.KondensatorLabel.Text = {'Kondensator'; ''};
            app.KondensatorWart = uieditfield(app.UIFigure, 'numeric');
            app.KondensatorWart.Limits = [0 Inf];
            app.KondensatorWart.ValueChangedFcn = createCallbackFcn(app, @KondensatorValue, true);
            app.KondensatorWart.Visible = 'off';
            app.KondensatorWart.Value = 1;
            app.CewkaJedn = uidropdown(app.UIFigure);
            app.CewkaJedn.Items = {'nH', 'μH', 'mH', 'H', 'kH', 'Dostosuj dla rezonansu'};
            app.CewkaJedn.ValueChangedFcn = createCallbackFcn(app, @CewkaJednChanged, true);
            app.CewkaJedn.Visible = 'off';
            app.CewkaJedn.Value = 'nH';
            app.KondensatorJedn = uidropdown(app.UIFigure);
            app.KondensatorJedn.Items = {'nF', 'μF', 'mF', 'F', 'kF', 'Dostosuj dla rezonansu'};
            app.KondensatorJedn.ValueChangedFcn = createCallbackFcn(app, @KondensatorJednChanged, true);
            app.KondensatorJedn.Visible = 'off';
            app.KondensatorJedn.Value = 'nF';
            app.ObliczButton = uibutton(app.UIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczPushed, true);
            app.ObliczButton.Visible = 'off';
            app.ObliczButton.Text = 'Oblicz';
            app.BodeShowAmpl = uiaxes(app.UIFigure);
            title(app.BodeShowAmpl, 'Amplituda')
            xlabel(app.BodeShowAmpl, 'Czestotliwosc[Hz]')
            ylabel(app.BodeShowAmpl, 'Wzmocnienie[dB]')
            app.BodeShowAmpl.Visible = 'off';
            app.BodeShowPhas = uiaxes(app.UIFigure);
            title(app.BodeShowPhas, 'Faza')
            xlabel(app.BodeShowPhas, 'Czestotliwosc[Hz]')
            ylabel(app.BodeShowPhas, 'Stopnie')
            app.BodeShowPhas.Visible = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Bode', 'Nieustalone'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @WyborFunkcji, true);
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Bode';
            app.wejsciowe = uibuttongroup(app.UIFigure);
            app.wejsciowe.SelectionChangedFcn = createCallbackFcn(app, @wejscioweSelectionChanged, true);
            app.wejsciowe.Title = 'Źródło napięcia';
            app.wejsciowe.Visible = 'off';
            app.EButton = uiradiobutton(app.wejsciowe);
            app.EButton.Visible = 'off';
            app.EButton.Text = 'E';
            app.EButton.Value = true;
            app.Esin2fButton = uiradiobutton(app.wejsciowe);
            app.Esin2fButton.Visible = 'off';
            app.Esin2fButton.Text = 'Esin(2πf)';
            app.NapiecieSlider = uislider(app.UIFigure);
            app.NapiecieSlider.Limits = [0 1000];
            app.NapiecieSlider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.NapiecieSlider.ValueChangedFcn = createCallbackFcn(app, @NapiecieValue, true);
            app.NapiecieSlider.Visible = 'off';
            app.NapiecieLabel = uilabel(app.UIFigure);
            app.NapiecieLabel.Visible = 'off';
            app.NapiecieLabel.Text = 'Wartość  [E]';
            app.JednostkaNapie = uidropdown(app.UIFigure);
            app.JednostkaNapie.Items = {'mV', 'V'};
            app.JednostkaNapie.ValueChangedFcn = createCallbackFcn(app, @JednostkaNapieValue, true);
            app.JednostkaNapie.Visible = 'off';
            app.JednostkaNapie.Value = 'V';
            app.CzestReczne = uieditfield(app.UIFigure, 'numeric');
            app.CzestReczne.ValueChangedFcn = createCallbackFcn(app, @CzestReczneValueChanged, true);
            app.CzestReczne.Visible = 'off';
            app.AmplReczne = uieditfield(app.UIFigure, 'numeric');
            app.AmplReczne.ValueChangedFcn = createCallbackFcn(app, @AmplReczneValueChanged, true);
            app.AmplReczne.Visible = 'off';
            app.Spokoprogramver50liczywszystkoanawetwicejLabel = uilabel(app.UIFigure);
            app.Spokoprogramver50liczywszystkoanawetwicejLabel.Text = 'Spoko program ver5.0 liczy wszystko a nawet więcej';
            app.PrezentacjaDropDownLabel = uilabel(app.UIFigure);
            app.PrezentacjaDropDownLabel.Text = 'Prezentacja';
            app.PrezentacjaDropDown = uidropdown(app.UIFigure);
            app.PrezentacjaDropDown.Items = {'Tryb Normalny', 'Rezonans prądowy [PRZYKŁAD]', 'Rezonans napięć [PRZYKŁAD]', 'Rezonans napięć Bode [PRZYKŁAD]', 'Rezonans prądów Bode [PRZYKŁAD]'};
            app.PrezentacjaDropDown.ValueChangedFcn = createCallbackFcn(app, @PrezentacjaCallback, true);
            app.PrezentacjaDropDown.Value = 'Tryb Normalny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektKS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end